classdef CeldaLeme < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdquisicionOSC01UIFigure   matlab.ui.Figure
        btn_imgHelp                matlab.ui.control.Image
        img_Logo                   matlab.ui.control.Image
        tab_TipoConexion           matlab.ui.container.TabGroup
        ConexionSerialTab          matlab.ui.container.Tab
        btn_serialRead             matlab.ui.control.Button
        btn_serialConnect          matlab.ui.control.Button
        ef_serialBPS               matlab.ui.control.NumericEditField
        lbl_serialBPS              matlab.ui.control.Label
        btn_UpdateSerialPort       matlab.ui.control.Button
        dd_serialPort              matlab.ui.control.DropDown
        lbl_serialPort             matlab.ui.control.Label
        tab_Ajustes                matlab.ui.container.TabGroup
        tab_AjusteCalibracion      matlab.ui.container.Tab
        CalibracionAutomticaLabel  matlab.ui.control.Label
        CalibracinoManualLabel     matlab.ui.control.Label
        ef_valPesoConocido         matlab.ui.control.EditField
        lbl_ch1Titulo_2            matlab.ui.control.Label
        btn_uploadPesoConocido     matlab.ui.control.Button
        btn_doCalAuto              matlab.ui.control.Button
        ef_valCalManual            matlab.ui.control.EditField
        lbl_ch1Titulo              matlab.ui.control.Label
        btn_uploadValCalManual     matlab.ui.control.Button
        btn_doCalManual            matlab.ui.control.Button
        tab_AjusteTiempo           matlab.ui.container.Tab
        btn_tRestore               matlab.ui.control.Button
        ef_endTime                 matlab.ui.control.NumericEditField
        lbl_endTime                matlab.ui.control.Label
        ef_startTime               matlab.ui.control.NumericEditField
        lbl_startTime              matlab.ui.control.Label
        lbl_NombreArchivo          matlab.ui.control.Label
        btn_abrirArchivoDAT        matlab.ui.control.Button
        btn_ExportMAT              matlab.ui.control.Button
        btn_ExportCSV              matlab.ui.control.Button
        btn_initRecord             matlab.ui.control.Button
        btn_stopRecord             matlab.ui.control.Button
        FuerzakgLabel              matlab.ui.control.Label
        lbl_valFuerzaKg            matlab.ui.control.Label
        graph_FuerzaKg             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serial;
        buttons_lock;
    end


    methods (Access = private)
        
        
        function result = LeerValores(app)
            
            d = uiprogressdlg(app.AdquisicionOSC01UIFigure, "Message", 'Limpiando Buffers...', 'Title', 'Iniciando protocolo serie...');
            
            try
                % Limpio los buffers
                flush(app.serial.connection,"input");
                flush(app.serial.connection, "output");
                
                % -------------------> Mensaje del uiprogress
                d.Value = 0.1;
                d.Message = "Verificando versión...";
                %-------------------------------------------]

                % Verifico la version
                pause(0.5);
                resp = writeread(app.serial.connection, "#check&"):
                if(resp == '#ok&')
                    % -------------------> Mensaje del uiprogress
                    d.Value = 0.5;
                    d.Message = "Leyendo valores...";
                    %-------------------------------------------]
                else
                    errorStruct.message = 'Error en la comunicacion con el módulo!';
                    errorStruct.identifier = 'LeerValores:checkcom';
                    error(errorStruct);
                end


                % -------------------> Mensaje del uiprogress
                d.Value = 0.8;
                d.Message = "Leyendo configuraciones...";
                %-------------------------------------------]    
                app.ef_valCalManual.Value = str2double(writeread(app.serial.connection,"#get-cal-val&"));
                pause(0.05);


            catch ME
                 result = 0;
                 switch(ME.identifier)
                     case 'transportclients:string:timeoutToken'
                         uialert(app.AdquisicionOSC01UIFigure, 'No se recibio respuesta del Osciloscopio', 'Error', Icon='error');
                     case 'transportlib:transport:invalidConnectionState'
                         uialert(app.AdquisicionOSC01UIFigure, 'Se ha perdido la conexion con el Osciloscopio', 'Error', Icon='error');
                     case 'LeerValores:NoChannel'
                         uialert(app.AdquisicionOSC01UIFigure, 'Se debe seleccionar al menos un canal', 'Error', Icon='error');
                     case 'LeerValores:ChanelError'
                         uialert(app.AdquisicionOSC01UIFigure, 'No se recivieron los valores RAW del Osciloscopio', 'Error', Icon='error');
                     otherwise
                         uialert(app.AdquisicionOSC01UIFigure, "[" + ME.identifier + "]: " + ME.message, 'Error', Icon='error');
                 end
            end

            close(d);
        end


        function plotFigures(app)
            if(app.buttons_lock)
                enableButtons(app);
                app.buttons_lock = 0;
            end

            bin2volt = @(raw, yoff, ymult, yzero)( (raw - yoff) .* ymult + yzero);

            
            % --------------------------------------------------------------------------------------------------------------------------> Canal 1
            % Si el CH1 esta habilitado, creo los vectores correspondientes
            if(strcmp(app.sw_ch1Enable.Value, "On"))
                app.computed_data.ch1 = bin2volt(app.raw_data.ch1, app.raw_data.ch1_off, app.raw_data.ch1_mult, app.raw_data.ch1_zero); % Volts
            else
                app.computed_data.ch1 = app.raw_data.ch1; % Volts en Cero
            end
            app.computed_data.t = (0:app.raw_data.dt:(length(app.raw_data.ch1) - 1) * app.raw_data.dt)' - app.raw_data.trig_pos;  % Tiempo
            plot(app.graph_FuerzaKg, (app.computed_data.t ./ app.factor_t) - app.offset_t ,  app.computed_data.ch1 .* app.factor_ch1);
            grid(app.graph_FuerzaKg, "on");
            % -----------------------------------------------------------------------------------------------------------------------------------


            % --------------------------------------------------------------------------------------------------------------------------> Canal 2
            % Si el CH1 esta habilitado, creo los vectores correspondientes
            if(strcmp(app.sw_ch2Enable.Value, "On"))
                app.computed_data.ch2 = bin2volt(app.raw_data.ch2, app.raw_data.ch2_off, app.raw_data.ch2_mult, app.raw_data.ch2_zero); % Volts
            else
                app.computed_data.ch2 = app.raw_data.ch2; % Volts en Cero
            end
            app.computed_data.t = (0:app.raw_data.dt:(length(app.raw_data.ch2) - 1) * app.raw_data.dt)' - app.raw_data.trig_pos;  % Tiempo
            plot(app.graph_CH2, (app.computed_data.t ./ app.factor_t) - app.offset_t ,  app.computed_data.ch2 .* app.factor_ch2);  % Plot
            grid(app.graph_CH2, "on");
            % -----------------------------------------------------------------------------------------------------------------------------------
                
            
            % Segun la unidad de tiempo seleccionada, cambio la leyenda
            switch(app.factor_t)
                case 1e-12
                    unit_t = 'ps';
                case 1e-9
                    unit_t = 'ns';
                case 0.000001
                    unit_t = 'µs';
                case 0.001
                    unit_t = 'ms';
                otherwise
                    unit_t = 's';
            end

            xlabel(app.graph_FuerzaKg, sprintf("Tiempo [%s]", unit_t));
            xlabel(app.graph_CH2, sprintf("Tiempo [%s]", unit_t));

            app.ef_valPretrigger.Value = app.raw_data.trig_pos ./ app.factor_t;
            app.lbl_valPretrigger.Text = sprintf("Pretriger [%s]", unit_t);


        end


        function updateValuesInBoxs(app)
            % app.ef_startTime.Value = min(app.computed_data.t);  %Tiempo de inicio
            % app.ef_endTime.Value = max(app.computed_data.t);    %Tiempo final
            % app.ef_ch1YMin.Value = app.graph_FuerzaKg.YLim(1);       %CH1 Ymin
            % app.ef_ch1YMax.Value = app.graph_FuerzaKg.YLim(2);       %CH1 Ymax
            % app.ef_ch2YMin.Value = app.graph_CH2.YLim(1);       %CH2 YMin
            % app.ef_ch2YMax.Value = app.graph_CH2.YLim(2);       %CH2 YmaX
            % app.ef_ch1Factor.Value = app.factor_ch1;            %Factor CH1
            % app.ef_ch2Factor.Value = app.factor_ch2;            %Factor CH2
        end


        function disableButtons(app)
            % app.dd_factorTime.Enable = "off";
            % 
            % app.ef_offsetTime.Enable = "off";
            % app.ef_startTime.Enable = "off";
            % app.ef_endTime.Enable = "off";
            % app.btn_tRestore.Enable = "off";
            % 
            % app.ef_ch1Factor.Enable = "off";
            % app.ef_ch1YMin.Enable = "off";
            % app.ef_ch1YMax.Enable = "off";
            % app.btn_ch1Restore.Enable = "off";
            % 
            % app.ef_ch2Factor.Enable = "off";
            % app.ef_ch2YMin.Enable = "off";
            % app.ef_ch2YMax.Enable = "off";
            % app.btn_ch2Restore.Enable = "off";
            % 
            % app.btn_ExportCSV.Enable = "off";
            % app.btn_ExportMAT.Enable = "off";
        end


        function enableButtons(app)
            % app.dd_factorTime.Enable = "on";
            % 
            % app.ef_offsetTime.Enable = "on";
            % app.ef_startTime.Enable = "on";
            % app.ef_endTime.Enable = "on";
            % app.btn_tRestore.Enable = "on";
            % 
            % app.ef_ch1Factor.Enable = "on";
            % app.ef_ch1YMin.Enable = "on";
            % app.ef_ch1YMax.Enable = "on";
            % app.btn_ch1Restore.Enable = "on";
            % 
            % app.ef_ch2Factor.Enable = "on";
            % app.ef_ch2YMin.Enable = "on";
            % app.ef_ch2YMax.Enable = "on";
            % app.btn_ch2Restore.Enable = "on";
            % 
            % app.btn_ExportCSV.Enable = "on";
            % app.btn_ExportMAT.Enable = "on";
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function onLoad(app)
            app.buttons_lock = 1;
            disableButtons(app);
            app.dd_serialPort.Items = serialportlist;

            % Valores por defecto
            app.dd_factorTime.Value = "1";  %Selector: Segundos
            app.factor_ch1 = 1;
            app.factor_ch2 = 1;
            app.factor_t = 1;
            app.offset_t = 0;
        end

        % Button pushed function: btn_serialConnect
        function Serial_Connect(app, event)
            if(strcmp(app.btn_serialConnect.Text, "Conectar"))
                
                d = uiprogressdlg(app.AdquisicionOSC01UIFigure, Message="Abriendo el puerto COM...", Title="Conectando...", ShowPercentage="on");
                
                try
                    %------------------------------------------------------------------------------> CONEXION SERIAL + CONFIG
                    %Realizo la conexion
                    app.serial.connection = serialport(app.dd_serialPort.Value, app.ef_serialBPS.Value, "Timeout", 10);
                    
                    d.Value = .1;
                    d.Message = "Limpiando Buffers...";
                    pause(1);
                    flush(app.serial.connection, "input");
                    flush(app.serial.connection, "output");
    
                    % Encoding
                    d.Value = .4;
                    d.Message = "Configurando codificacion...";
                    writeline(app.serial.connection,"DATA:ENCDG ASCII"); pause(0.05);
                    writeline(app.serial.connection,"HEADER OFF");  pause(0.05);
                    writeline(app.serial.connection,"DATA:WIDTH 2"); pause(0.05);
                    
                    % Verifico el Modelo
                    d.Value = .8;
                    d.Message = "Verificando modelo del Osciloscopio...";
                    resp = writeread(app.serial.connection, "ID?");
                    
                    d.Value = 1;
                    pause(1);
                    if ~strcmp(strip(resp, 'right'), "ID TEK/TDS 360,CF:91.1CT,FV:v1.09")
                        e.message = 'Erro, el ID no corresponde al Osciloscopio TDS360';
                        e.identifier = 'Serial:IDError';
                        error(e);                        
                    end

                    uialert(app.AdquisicionOSC01UIFigure, "Conexión Establecida", "Conexion", Icon='success');
                    app.btn_serialConnect.Text = "Desconectar";
                    app.btn_serialRead.Enable = 1;
                    
                catch ME
                    app.serial.connection = [];     %Close(serial)
                    app.btn_serialRead.Enable = 0;
                    switch(ME.identifier)
                        case 'transportclients:string:timeoutToken'
                            uialert(app.AdquisicionOSC01UIFigure, 'No se recibio respuesta del Osciloscopio', 'Error', Icon='error');
                        case 'serialport:serialport:ConnectionFailed'
                            uialert(app.AdquisicionOSC01UIFigure, 'El puerto se encuentra en uso por otra aplicacion', 'Error', Icon='error');
                        case 'Serial:IDError'
                            uialert(app.AdquisicionOSC01UIFigure, "El Osciloscopio no es el Tektronix TDS360", "Conexion", Icon='error');
                        otherwise
                            uialert(app.AdquisicionOSC01UIFigure, "[" + ME.identifier + "]: " + ME.message, 'Error', Icon='error');
                    end
                end

                close(d);
            else
                app.btn_serialConnect.Text = "Conectar";
                app.serial.connection = []; %Close(Serial)
                app.btn_serialRead.Enable = 0;
            end
        end

        % Button pushed function: btn_serialRead
        function Serial_Read(app, event)
            if LeerValores(app)
                app.lbl_NombreArchivo.Visible = 'off';
                plotFigures(app);

                axis(app.graph_FuerzaKg,"auto");
                axis(app.graph_CH2,"auto");
    
                updateValuesInBoxs(app);
            end
        end

        % Button pushed function: btn_ExportMAT
        function Export_MAT(app, event)
            t = datetime;
            t.Format = 'yyyy.MM.dd_HH.mm';
            
            clear dt offset_t ch1 ch2 trig_pos;
            try
                dt = app.raw_data.dt;
                offset_t = app.offset_t * app.factor_t; %#ok<*NASGU,*ADPROPLC> 
                trig_pos = app.raw_data.trig_pos;
              
                ch1.raw = app.raw_data.ch1;
                ch1.ymult = app.raw_data.ch1_mult;
                ch1.yoff = app.raw_data.ch1_off;
                ch1.yzero = app.raw_data.ch1_zero;
                ch1.factor = app.factor_ch1;
                ch1.titulo = app.ef_valCalManual.Value;
                ch1.leyenda = app.ef_ch1Etiqueta.Value; %#ok<*STRNU> 
                
                ch2.raw = app.raw_data.ch2;
                ch2.ymult = app.raw_data.ch2_mult;
                ch2.yoff = app.raw_data.ch2_off;
                ch2.yzero = app.raw_data.ch2_zero;
                ch2.factor = app.factor_ch2;
                ch2.titulo = app.ef_ch2Titulo.Value;
                ch2.leyenda = app.ef_ch2Etiqueta.Value;
                
    
                uisave({'dt', 'offset_t', 'trig_pos', 'ch1', 'ch2'}, string(t))
            catch ME
                switch(ME.identifier)
                    case 'MATLAB:structRefFromNonStruct'
                        uialert(app.AdquisicionOSC01UIFigure, 'Se debe primero cargar alguna matriz de datos para poder exportar', 'Error', Icon='error');
                    otherwise
                        uialert(app.AdquisicionOSC01UIFigure, "[" + ME.identifier + "]: " + ME.message, "Error", Icon='error');
                end
            end
            clear dt ch1 ch2;
        end

        % Button pushed function: btn_ExportCSV
        function Export_CSV(app, event)
            t = datetime;
            t.Format = 'yyyy.MM.dd_HH.mm';
            
            [filename,filepath] = uiputfile('*.csv', 'Elegir ubicacion', string(t) + ".csv");
            if ischar(filename)
                mtx = [app.computed_data.t, app.computed_data.ch1, app.computed_data.ch2];
                T = array2table(mtx, 'VariableNames', {'Tiempo', 'CH1', 'CH2'});
                writetable(T, [filepath filename], Delimiter='tab');
            end
        end

        % Button pushed function: btn_abrirArchivoDAT
        function Import_MAT(app, event)
            %Solicito que se abra el explorador para seleccionar el archivo
            %Especificamente con la extension .mat
            [file, path] = uigetfile('*.mat');
            
            if ~isequal(file,0)
                %Consulto si en el archivo, estan todas las variables que necesito.
                %Estas son: raw_ch1, raw_ch2, dt, factor_ch1, factor_ch2, raw_ch1_v_div y raw_ch2_v_div
                ruta = [path file];    
                info_var = ismember(who("-file", ruta), {'dt', 'offset_t', 'trig_pos', 'ch1', 'ch2'});
                if all(info_var) && length(info_var) == 5
            
	                %Si estan todas, las cargo y las almaceno en donde corresponde (variable y casilleros)
	                load(ruta, 'dt', 'offset_t', 'trig_pos', 'ch1', 'ch2');
                    
                    try
                        % Actualizo las variables internas
                        app.factor_t = 1;
                        app.raw_data.dt = dt;
                        app.offset_t = offset_t;
                        app.raw_data.trig_pos = trig_pos;
                        app.raw_data.ch1 = ch1.raw;
                        app.raw_data.ch1_mult = ch1.ymult;
                        app.raw_data.ch1_off = ch1.yoff;
                        app.raw_data.ch1_zero = ch1.yzero;
                        app.factor_ch1 = ch1.factor;
                        app.raw_data.ch2 = ch2.raw;
                        app.raw_data.ch2_mult = ch2.ymult;
                        app.raw_data.ch2_off = ch2.yoff;
                        app.raw_data.ch2_zero = ch2.yzero;
                        app.factor_ch2 = ch2.factor;
    
    
                        %Actualizo los campos disponibles para el usuario
                        app.ef_ch1Factor.Value = ch1.factor;
                        app.ef_valCalManual.Value = ch1.titulo;
                        app.ef_ch1Etiqueta.Value = ch1.leyenda;
                        app.ef_ch2Factor.Value = ch2.factor;
                        app.ef_ch2Titulo.Value = ch2.titulo;
                        app.ef_ch2Etiqueta.Value = ch2.leyenda;
                        app.ef_offsetTime.Value = offset_t;
                        ylabel(app.graph_FuerzaKg, ch1.leyenda);
                        ylabel(app.graph_CH2, ch2.leyenda);
                        title(app.graph_FuerzaKg, ch1.titulo);
                        title(app.graph_CH2, ch2.titulo);
    
    
	                    app.dd_factorTime.Value = "1"; %Selector en 'Segundos'
                
	                    %Muestro el plot correspondiente
	                    plotFigures(app);
	                    updateValuesInBoxs(app);
                
	                    %Muestro el nombre del archivo cargado
                        uialert(app.AdquisicionOSC01UIFigure, "Archivo Leido: <font color='green'>" + file + "</font>", 'Apertura de Archivo .MAT', Icon='success', Interpreter='html');
    
                        %Habilito los botones
                        enableButtons(app);
                        app.lbl_NombreArchivo.Text = "Archivo: <font color='green'>" + file + "</font>";
                        app.lbl_NombreArchivo.Visible = 'on';
                    catch ME
                        app.lbl_NombreArchivo.Visible = 'off';
                        switch ME.identifier
                            case 'MATLAB:nonExistentField'
                                uialert(app.AdquisicionOSC01UIFigure, 'Los datos no corresponden a los exportados por esta aplicacion!', 'Error', Icon='error');
                            otherwise
                                uialert(app.AdquisicionOSC01UIFigure, "[" + ME.identifier + "]: " + ME.message, 'Error', Icon='error');
                        end
                    end
                else
	                uialert(app.AdquisicionOSC01UIFigure, 'El archivo no contiene las variables necesarias para esta aplicacion!', 'Error al Importar', Icon='error');
                end
            end

        end

        % Button pushed function: btn_UpdateSerialPort
        function Change_Serial_BPS(app, event)
            app.dd_serialPort.Items = serialportlist;
        end

        % Value changed function: ef_startTime
        function Change_Start_Time(app, event)
            init_val = app.ef_startTime.Value;
            end_val = app.graph_FuerzaKg.XLim(2);

            if(init_val > end_val)
                msgbox("El valor inicial no puede superar al valor final!");
            else
                xlim(app.graph_FuerzaKg, [init_val, end_val]);
                xlim(app.graph_CH2, [init_val, end_val]);
                ylim(app.graph_FuerzaKg, [app.ef_ch1YMin.Value, app.ef_ch1YMax.Value]);
                ylim(app.graph_CH2, [app.ef_ch2YMin.Value, app.ef_ch2YMax.Value]);
            end
        end

        % Value changed function: ef_endTime
        function Change_End_Time(app, event)
            init_val = app.graph_FuerzaKg.XLim(1);
            end_val = app.ef_endTime.Value;
        

            if(end_val < init_val)
                msgbox("El valor final no puede superar al valor inicial!");
            else
                xlim(app.graph_FuerzaKg, [init_val, end_val]);
                xlim(app.graph_CH2, [init_val, end_val]);
                ylim(app.graph_FuerzaKg, [app.ef_ch1YMin.Value, app.ef_ch1YMax.Value]);
                ylim(app.graph_CH2, [app.ef_ch2YMin.Value, app.ef_ch2YMax.Value]);
            end
        end

        % Button pushed function: btn_tRestore
        function Restore_Time_Axis(app, event)
            plotFigures(app);

            app.graph_FuerzaKg.XLimMode = "auto";
            app.graph_CH2.XLimMode = "auto";

            app.ef_startTime.Value = app.graph_FuerzaKg.XLim(1);
            app.ef_endTime.Value = app.graph_FuerzaKg.XLim(2);

            ylim(app.graph_FuerzaKg, [app.ef_ch1YMin.Value, app.ef_ch1YMax.Value]);
            ylim(app.graph_CH2, [app.ef_ch2YMin.Value, app.ef_ch2YMax.Value]);
            
        end

        % Image clicked function: btn_imgHelp
        function Click_Help_Window(app, event)
            Ventana_Help;
        end

        % Close request function: AdquisicionOSC01UIFigure
        function Close_App(app, event)
            delete(Ventana_Help);
            delete(app)
        end

        % Image clicked function: img_Logo
        function img_LogoImageClicked(app, event)
            web('https://leme.ing.unlp.edu.ar/');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AdquisicionOSC01UIFigure = uifigure('Visible', 'off');
            app.AdquisicionOSC01UIFigure.Name = 'Adquisicion OSC01';
            app.AdquisicionOSC01UIFigure.Icon = fullfile(pathToMLAPP, 'assets', 'icon.png');
            app.AdquisicionOSC01UIFigure.CloseRequestFcn = createCallbackFcn(app, @Close_App, true);
            app.graph_FuerzaKg = uiaxes(app.AdquisicionOSC01UIFigure);
            title(app.graph_FuerzaKg, 'Gráfica')
            xlabel(app.graph_FuerzaKg, 'Tiempo [s]')
            ylabel(app.graph_FuerzaKg, 'Fuerza [kg]')
            zlabel(app.graph_FuerzaKg, 'Z')
            app.lbl_valFuerzaKg = uilabel(app.AdquisicionOSC01UIFigure);
            app.lbl_valFuerzaKg.Text = '0.00';
            app.FuerzakgLabel = uilabel(app.AdquisicionOSC01UIFigure);
            app.FuerzakgLabel.Text = 'Fuerza [kg]:';
            app.btn_stopRecord = uibutton(app.AdquisicionOSC01UIFigure, 'push');
            app.btn_stopRecord.Text = 'Detener registro';
            app.btn_initRecord = uibutton(app.AdquisicionOSC01UIFigure, 'push');
            app.btn_initRecord.Text = 'Iniciar registro';
            app.btn_ExportCSV = uibutton(app.AdquisicionOSC01UIFigure, 'push');
            app.btn_ExportCSV.ButtonPushedFcn = createCallbackFcn(app, @Export_CSV, true);
            app.btn_ExportCSV.Text = 'Exportar en Archivo .CSV';
            app.btn_ExportMAT = uibutton(app.AdquisicionOSC01UIFigure, 'push');
            app.btn_ExportMAT.ButtonPushedFcn = createCallbackFcn(app, @Export_MAT, true);
            app.btn_ExportMAT.Tooltip = {'El formato .MAT guarda los metadatos correspondientes a la configuracion de las gráficas.'};
            app.btn_ExportMAT.Text = 'Exportar en Archivo .MAT';
            app.btn_abrirArchivoDAT = uibutton(app.AdquisicionOSC01UIFigure, 'push');
            app.btn_abrirArchivoDAT.ButtonPushedFcn = createCallbackFcn(app, @Import_MAT, true);
            app.btn_abrirArchivoDAT.Text = 'Importar Archivo .MAT';
            app.lbl_NombreArchivo = uilabel(app.AdquisicionOSC01UIFigure);
            app.lbl_NombreArchivo.Visible = 'off';
            app.tab_Ajustes = uitabgroup(app.AdquisicionOSC01UIFigure);
            app.tab_AjusteCalibracion = uitab(app.tab_Ajustes);
            app.tab_AjusteCalibracion.Title = 'Calibración';
            app.btn_doCalManual = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_doCalManual.Text = 'Calibrar';
            app.btn_uploadValCalManual = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_uploadValCalManual.Text = 'Leer';
            app.lbl_ch1Titulo = uilabel(app.tab_AjusteCalibracion);
            app.lbl_ch1Titulo.Text = 'Valor de Calibración:';
            app.ef_valCalManual = uieditfield(app.tab_AjusteCalibracion, 'text');
            app.ef_valCalManual.Value = '0.01';
            app.btn_doCalAuto = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_doCalAuto.Text = 'Iniciar Calibración';
            app.btn_uploadPesoConocido = uibutton(app.tab_AjusteCalibracion, 'push');
            app.btn_uploadPesoConocido.Text = 'Cargar';
            app.lbl_ch1Titulo_2 = uilabel(app.tab_AjusteCalibracion);
            app.lbl_ch1Titulo_2.Text = 'Peso Conocido [kg]:';
            app.ef_valPesoConocido = uieditfield(app.tab_AjusteCalibracion, 'text');
            app.ef_valPesoConocido.Value = '1';
            app.CalibracinoManualLabel = uilabel(app.tab_AjusteCalibracion);
            app.CalibracinoManualLabel.Text = 'Calibración Manual:';
            app.CalibracionAutomticaLabel = uilabel(app.tab_AjusteCalibracion);
            app.CalibracionAutomticaLabel.Text = 'Calibración Automática:';
            app.tab_AjusteTiempo = uitab(app.tab_Ajustes);
            app.tab_AjusteTiempo.Tooltip = {''};
            app.tab_AjusteTiempo.Title = 'Ajuste de Tiempo';
            app.lbl_startTime = uilabel(app.tab_AjusteTiempo);
            app.lbl_startTime.Text = 'Tiempo Inicial:';
            app.ef_startTime = uieditfield(app.tab_AjusteTiempo, 'numeric');
            app.ef_startTime.ValueChangedFcn = createCallbackFcn(app, @Change_Start_Time, true);
            app.lbl_endTime = uilabel(app.tab_AjusteTiempo);
            app.lbl_endTime.Text = 'Tiempo Final:';
            app.ef_endTime = uieditfield(app.tab_AjusteTiempo, 'numeric');
            app.ef_endTime.ValueChangedFcn = createCallbackFcn(app, @Change_End_Time, true);
            app.btn_tRestore = uibutton(app.tab_AjusteTiempo, 'push');
            app.btn_tRestore.ButtonPushedFcn = createCallbackFcn(app, @Restore_Time_Axis, true);
            app.btn_tRestore.Icon = fullfile(pathToMLAPP, 'assets', 'restore.png');
            app.btn_tRestore.Text = '';
            app.tab_TipoConexion = uitabgroup(app.AdquisicionOSC01UIFigure);
            app.ConexionSerialTab = uitab(app.tab_TipoConexion);
            app.ConexionSerialTab.Title = 'Conexion Serial';
            app.lbl_serialPort = uilabel(app.ConexionSerialTab);
            app.lbl_serialPort.Text = 'Puerto COM:';
            app.dd_serialPort = uidropdown(app.ConexionSerialTab);
            app.btn_UpdateSerialPort = uibutton(app.ConexionSerialTab, 'push');
            app.btn_UpdateSerialPort.ButtonPushedFcn = createCallbackFcn(app, @Change_Serial_BPS, true);
            app.btn_UpdateSerialPort.Text = 'Actualizar';
            app.lbl_serialBPS = uilabel(app.ConexionSerialTab);
            app.lbl_serialBPS.Text = 'Velocidad:';
            app.ef_serialBPS = uieditfield(app.ConexionSerialTab, 'numeric');
            app.ef_serialBPS.ValueDisplayFormat = '%.0f';
            app.ef_serialBPS.Value = 9600;
            app.btn_serialConnect = uibutton(app.ConexionSerialTab, 'push');
            app.btn_serialConnect.ButtonPushedFcn = createCallbackFcn(app, @Serial_Connect, true);
            app.btn_serialConnect.Text = 'Conectar';
            app.btn_serialRead = uibutton(app.ConexionSerialTab, 'push');
            app.btn_serialRead.ButtonPushedFcn = createCallbackFcn(app, @Serial_Read, true);
            app.btn_serialRead.Enable = 'off';
            app.btn_serialRead.Text = 'Leer Valores';
            app.img_Logo = uiimage(app.AdquisicionOSC01UIFigure);
            app.img_Logo.ImageClickedFcn = createCallbackFcn(app, @img_LogoImageClicked, true);
            app.img_Logo.ImageSource = fullfile(pathToMLAPP, 'assets', 'Logo_LEME.png');
            app.btn_imgHelp = uiimage(app.AdquisicionOSC01UIFigure);
            app.btn_imgHelp.ImageClickedFcn = createCallbackFcn(app, @Click_Help_Window, true);
            app.btn_imgHelp.ImageSource = fullfile(pathToMLAPP, 'assets', 'help-icon.png');
            % Show the figure after all components are created
            app.AdquisicionOSC01UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CeldaLeme

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AdquisicionOSC01UIFigure)

                % Execute the startup function
                runStartupFcn(app, @onLoad)
            else

                % Focus the running singleton app
                figure(runningApp.AdquisicionOSC01UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdquisicionOSC01UIFigure)
        end
    end
end